classdef P2_TheBorg_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_5                       matlab.ui.control.Label
        GridLayout                    matlab.ui.container.GridLayout
        INS1                          matlab.ui.control.TextArea
        ScoreEditFieldLabel           matlab.ui.control.Label
        INS2                          matlab.ui.control.TextArea
        TheWondersofWindLabel         matlab.ui.control.Label
        TotalEnergyLabel              matlab.ui.control.Label
        VTG                           matlab.ui.control.Gauge
        STG                           matlab.ui.control.Gauge
        LTG                           matlab.ui.control.Gauge
        SmallTurbineGaugeLabel        matlab.ui.control.Label
        ScoreEditField_2Label         matlab.ui.control.Label
        VTGSwitch                     matlab.ui.control.Switch
        LTGSwitch                     matlab.ui.control.Switch
        STGSwitch                     matlab.ui.control.Switch
        VTGLamp                       matlab.ui.control.Lamp
        LTGLamp                       matlab.ui.control.Lamp
        STGLamp                       matlab.ui.control.Lamp
        TimerEditFieldLabel           matlab.ui.control.Label
        VTGLabel                      matlab.ui.control.Label
        LTGLabel                      matlab.ui.control.Label
        ScoreEditField_3Label         matlab.ui.control.Label
        LinearGauge                   matlab.ui.control.LinearGauge
        STGScore                      matlab.ui.control.NumericEditField
        LTGScore                      matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        TimerEditField                matlab.ui.control.NumericEditField
        VTGScore                      matlab.ui.control.NumericEditField
        TabGroup                      matlab.ui.container.TabGroup
        PartyMode                     matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        TEEF                          matlab.ui.control.NumericEditField
        TotalEnergyEditFieldLabel     matlab.ui.control.Label
        VTSEF                         matlab.ui.control.NumericEditField
        VerticalTurbineScoreEditFieldLabel  matlab.ui.control.Label
        STSEF                         matlab.ui.control.NumericEditField
        SmallTurbineScoreEditFieldLabel  matlab.ui.control.Label
        LTSEF                         matlab.ui.control.NumericEditField
        LargeTurbineScoreEditFieldLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        ReturntoHomeButton            matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        ThankyouforPlayingLabel       matlab.ui.control.Label
        Image3_2                      matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        YOUHAVEREACHEDMAXENERGYLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        Tryagain                      matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        TEEFF                         matlab.ui.control.NumericEditField
        TotalEnergyEditFieldLabel_2   matlab.ui.control.Label
        LTSEFF                        matlab.ui.control.NumericEditField
        LargeTurbineScoreEditFieldLabel_2  matlab.ui.control.Label
        STSEFF                        matlab.ui.control.NumericEditField
        SmallTurbineScoreEditFieldLabel_2  matlab.ui.control.Label
        VTSEFF                        matlab.ui.control.NumericEditField
        VerticalTurbineScoreEditFieldLabel_2  matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        ReturntoHomeButton_2          matlab.ui.control.Button
        ThankyouforPlayingPleasetryagainLabel  matlab.ui.control.Label
        YoudidnotreachmaxenergyLabel  matlab.ui.control.Label
        Warning                       matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        NoTurbineActiveLabel          matlab.ui.control.Label
        WARNlabel                     matlab.ui.control.Label
        CD3                           matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        Countdown3                    matlab.ui.control.Image
        CD2                           matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        Countdown2                    matlab.ui.control.Image
        CD1                           matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        Countdown1                    matlab.ui.control.Image
        Intro                         matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        ImReadyButton                 matlab.ui.control.Button
        Image4                        matlab.ui.control.Image
        SPACE                         matlab.ui.control.Label
    end

    
    properties (Access = public)
    %RB = serialport(tail(serialportlist), 9600); %create connection to serial port
    RB = serialport("COM5", 9600); %create connection to serial port

    c = 0;
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% set counter varibles and data up in good format
            %write to data collection doc
            time = datetime;
            fopen("P2_Borg_UI_Data.csv","A")
            Out1 = sprintf("%s Starting data capture:", time);
            writelines(" ","P2_Borg_UI_Data.csv", "WriteMode","append")
            writelines(" ","P2_Borg_UI_Data.csv", "WriteMode","append")
            writelines(Out1,"P2_Borg_UI_Data.csv", "WriteMode","append")

    %set display order
            uistack(app.TabGroup,"bottom")
            app.TabGroup.SelectedTab= app.Intro;


        end

        % Value changed function: STGSwitch
        function STGSwitchValueChanged(app, event)
          %% Small Turbine Activation Switch
            value = app.STGSwitch.Value; 
            if value==1 %if switch is active
                app.STGLamp.Color = [0 1 0]; %set color to green 
                app.STG.Enable = 1; % enable ST gauge
            else 
                app.STGLamp.Color = [0.50,0.50,0.50]; %set lamp to grey
                app.STG.Enable = 0; % disable ST gauge
            end
        end

        % Value changed function: LTGSwitch
        function LTGSwitchValueChanged(app, event)
            %% Large Turbine Activation Switch
            value = app.LTGSwitch.Value;
            if value==1 %if switch is active
                app.LTGLamp.Color = [0 1 0]; %set color to green 
                app.LTG.Enable = 1; % enable LT gauge
            else
                app.LTGLamp.Color = [0.50,0.50,0.50]; %set lamp to grey
                app.LTG.Enable = 0; % disable LT guage.
            end
        end

        % Value changed function: VTGSwitch
        function VTGSwitchValueChanged(app, event)
          %% Vertical Turbine Switch
            value = app.VTGSwitch.Value;
            if value==1 %if switch is active
                app.VTGLamp.Color = [0 1 0]; %set color to green 
                app.VTG.Enable = 1; %enable VT gauge
            else
                app.VTGLamp.Color = [0.50,0.50,0.50]; %set lamp to grey
                app.VTG.Enable = 0; %disable VT guage.
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
      
          %% sequence that sets countdown images visibility
        
       % %    % bring number 3 to front, set visible, and enable.   
       %     uistack(app.TabGroup, "bottom");
       %     app.TabGroup.Visible = 1;
       % 
       %     app.TabGroup.SelectedTab=app.CD3;
       %     pause(1.8)
       % 
       %     app.TabGroup.SelectedTab=app.CD2;
       %     pause(1)
       % 
       %     app.TabGroup.SelectedTab=app.CD1;
       %     pause(1)
       % 
       %     uistack(app.TabGroup, "top");
       %     app.TabGroup.Visible = 0;
       
       
            %% set arduino to game mode and prep

           % set gauge values to 0 for all turbines
           app.STG.Value = 0;
           app.LTG.Value = 0;
           app.VTG.Value = 0;
           % set communication values to 0
           SValue = 0;
           LValue = 0;
           VValue = 0;
           % set timer to 5 seconds
           app.TimerEditField.Value = 5;
           % Set variable for value of switches
           SW = app.STGSwitch.Value;
           LW = app.LTGSwitch.Value;
           VW = app.VTGSwitch.Value;


 %% Warning function   
           if ((SW+LW+VW)>0) %make sure at least one switch is active
           
               %display countdown:
uistack(app.TabGroup, "bottom");
           app.TabGroup.Visible = 1;
          
           app.TabGroup.SelectedTab=app.CD3;
           pause(1.8)

           app.TabGroup.SelectedTab=app.CD2;
           pause(1)
           
           app.TabGroup.SelectedTab=app.CD1;
           pause(0.5)
          
           uistack(app.TabGroup, "top");
           app.TabGroup.Visible = 0;
        

           %% set arduino to game mode and prep

           app.GridLayout.BackgroundColor = [1,1,0.9]; %set background yellow
       % enable part two of instructions
           app.INS1.Visible=0;
           app.INS1.Enable=0;
           app.INS2.Visible=1;
           app.INS2.Enable=1;
           flush(app.RB) % flush buffer
        % disable simulation button and activation buttons
        app.StartButton.Enable = 0; 
        app.VTGSwitch.Enable = 0;
        app.LTGSwitch.Enable = 0;
        app.STGSwitch.Enable = 0;
        %send message to arduino to start 'game mode'
        message = 'g';
        writeline(app.RB,message);
        
        %delay for clarity
        pause(0.01)
 
        %play wind sound 
         [y,Fs] = audioread("audiowind.ogg");
            sound(y,Fs)
        pause(0.1)
        % send arduino message of which turbine is active (backup)
       % rain = string(fprintf("%s%s%s", SW, LW, VW));
        %writeline(app.RB,rain);
          
        %% countdown loop 
           for x=1:50 %equals out to 5 second timer
              flush(app.RB) % flush buffer

              pause(0.1) % communication delay
              % set back timer .5 seconds every pass
              tv=app.TimerEditField.Value;
              app.TimerEditField.Value = tv-0.1;
              
              %read arduino message and turn it into strings
              ArdMsg = readline(app.RB);
              ArdMsg = readline(app.RB);
              k = char(ArdMsg);
              % take encoded message for each respective turbine
              St = str2num(k(1:4));
              Lt = str2num(k(5:8));
              Vt = str2num(k(9:12));

              if SW % check if active
            % take in value and map to higher output to display efficiency
              SValue = 15*(9000-St); 

              % set values to display on S gauge and score
              app.STG.Value = SValue;
              app.STGScore.Value = SValue;
              end

              if LW
            % take in value and map to higher output to display efficiency
              LValue = 20*(9000-Lt);

              % set values to display on L gauge and score
              app.LTG.Value = LValue;
              app.LTGScore.Value = LValue;
              end

              if VW
            % take in value and map to higher output to display efficiency
              VValue = 10*(9000-Vt);

              % set values to display on V gauge and score
              app.VTG.Value = VValue; 
              app.VTGScore.Value = VValue;
              end
               
              if ((SW+LW+VW)>0)
             %set linear gauge to average of avtive gauges 
               LinearValue = (SValue+LValue+VValue)/(SW+LW+VW);

               %send arduino lv vale to set color led display
              app.LinearGauge.Value = LinearValue;
              LinOut = sprintf("n%4.f",LinearValue);

              writeline(app.RB, LinOut);
             % disp(LinOut)
              end
                       end
           %% Stop Reading Arduino

           % send message to arduino to tell it to exit game mode
            message = 'e';
        writeline(app.RB,message);

           %% Reset
           %reset background color to white and timer to 5 seocnds
           app.GridLayout.BackgroundColor = [1,1,1];
           app.TimerEditField.Value = 5;

          % enable simulation button and activation buttons 
           app.StartButton.Enable = 1;
        app.VTGSwitch.Enable = 1;
        app.LTGSwitch.Enable = 1;
        app.STGSwitch.Enable = 1;

        %enable first instructions
           app.INS1.Visible=1;
           app.INS1.Enable=1;
           app.INS2.Visible=0;
           app.INS2.Enable=0;

       %% Party mode
       LV = app.LinearGauge.Value;
       if LV > 60  %check if linear gauge value reaches certain threshold
         %enable and set to visble  party mode tab
           uistack(app.TabGroup,"bottom");
           app.TabGroup.Visible=1; 
         app.TabGroup.SelectedTab = app.PartyMode;
     %set values on party mode screen 
         app.LTSEF.Value = LValue;
        app.STSEF.Value = SValue;
        app.VTSEF.Value = VValue;
        app.TEEF.Value = LV;
    
        %send party mode activation to arduino   
        message = 'p';
 writeline(app.RB,message);
 
 % play win sound
 [y,Fs] = audioread("Winningsounds.wav");
            sound(y,Fs)
 
       end
%% try again
       if LV <= 60 %check if linear gauge value is below certain threshold
          %enable and set to visble  Try again tab
           uistack(app.TabGroup,"bottom");
           app.TabGroup.Visible=1; 
           app.TabGroup.SelectedTab = app.Tryagain;
          
           %set score values in menu
           app.LTSEF.Value = LValue;
        app.STSEFF.Value = SValue;
        app.VTSEFF.Value = VValue;
        app.TEEFF.Value = LV;
         
       end
       %% update user counter variable
        time = datetime;
        app.c = app.c+1;
       Output = sprintf("%s, %3.i, LV,  %3.f, VT, %3.i, ST,  %3.i, LT, %3.i", time, app.c, LV, VValue, SValue, LValue);
       writelines(Output, "P2_Borg_UI_Data.csv", "WriteMode","append") 
   end
           
           %% alternate warning
        if ((SW+LW+VW)==0) %check if no turbines are active
          % set warning to cover screen
           uistack(app.TabGroup,"bottom");
           app.TabGroup.Visible=1; 
           app.TabGroup.SelectedTab = app.Warning;
               %app.WARNlabel.Enable=1;
               %app.WARNlabel.Visible=1;
               pause(3) %pause 3 seconds to read
           %disapear warning label
               %app.WARNlabel.Enable=0;
               %app.WARNlabel.Visible=0;
               app.TabGroup.Visible=0;
             uistack(app.TabGroup,"top");
        end
          
        end

        % Button pushed function: ReturntoHomeButton
        function ReturntoHomeButtonPushed(app, event)
                    %if party mode 'return to home' button is pushed disable party mode and return to home 
            app.TabGroup.SelectedTab=app.Intro;

        end

        % Button pushed function: ReturntoHomeButton_2
        function ReturntoHomeButton_2Pushed(app, event)
                 %if Try Again 'return to home' button is pushed disable Try again and return to home 
            app.TabGroup.SelectedTab=app.Intro;

        end

        % Button pushed function: ImReadyButton
        function ImReadyButtonPushed(app, event)
             app.TabGroup.Visible=0;
             %reset to isplay and clear all previous values
                      uistack(app.TabGroup,"top");
                      app.STGSwitch.Value=0;
                      app.VTGSwitch.Value=0;
                      app.LTGSwitch.Value=0;
          
                      STGSwitchValueChanged(app, event)
                      VTGSwitchValueChanged(app, event)
                      LTGSwitchValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SPACE = uilabel(app.UIFigure);
            app.SPACE.Text = '';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PartyMode = uitab(app.TabGroup);
            app.GridLayout2 = uigridlayout(app.PartyMode);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'party-popper-emoji-2012x2048-lhq1rf8v.png');
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'party-popper-emoji-2012x2048-lhq1rf8v - Copy.png');
            app.YOUHAVEREACHEDMAXENERGYLabel = uilabel(app.GridLayout2);
            app.YOUHAVEREACHEDMAXENERGYLabel.Text = 'YOU HAVE REACHED MAX ENERGY!!';
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'generator-wind-turbine-cartoon-vector-51916912-removebg-preview.png');
            app.Image3_2 = uiimage(app.GridLayout2);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'generator-wind-turbine-cartoon-vector-51916912-removebg-preview.png');
            app.ThankyouforPlayingLabel = uilabel(app.GridLayout2);
            app.ThankyouforPlayingLabel.Text = 'Thank you for Playing!';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = 'Please Continue reading the exhibit and learn more about the potential of Offshore wind! Or you can play again!';
            app.ReturntoHomeButton = uibutton(app.GridLayout2, 'push');
            app.ReturntoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoHomeButtonPushed, true);
            app.ReturntoHomeButton.Text = 'Return to Home';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = 'When you are done, be sure to fill out the form so we are able to improve our exhibit.';
            app.LargeTurbineScoreEditFieldLabel = uilabel(app.GridLayout2);
            app.LargeTurbineScoreEditFieldLabel.Text = 'Large Turbine Score';
            app.LTSEF = uieditfield(app.GridLayout2, 'numeric');
            app.LTSEF.ValueDisplayFormat = '%.1f%%';
            app.LTSEF.Editable = 'off';
            app.SmallTurbineScoreEditFieldLabel = uilabel(app.GridLayout2);
            app.SmallTurbineScoreEditFieldLabel.Text = 'Small Turbine Score';
            app.STSEF = uieditfield(app.GridLayout2, 'numeric');
            app.STSEF.ValueDisplayFormat = '%.1f%%';
            app.STSEF.Editable = 'off';
            app.VerticalTurbineScoreEditFieldLabel = uilabel(app.GridLayout2);
            app.VerticalTurbineScoreEditFieldLabel.Text = 'Vertical Turbine Score';
            app.VTSEF = uieditfield(app.GridLayout2, 'numeric');
            app.VTSEF.ValueDisplayFormat = '%.1f%%';
            app.VTSEF.Editable = 'off';
            app.TotalEnergyEditFieldLabel = uilabel(app.GridLayout2);
            app.TotalEnergyEditFieldLabel.Text = 'Total Energy';
            app.TEEF = uieditfield(app.GridLayout2, 'numeric');
            app.TEEF.ValueDisplayFormat = '%.1f%%';
            app.TEEF.Editable = 'off';
            app.Tryagain = uitab(app.TabGroup);
            app.GridLayout3 = uigridlayout(app.Tryagain);
            app.YoudidnotreachmaxenergyLabel = uilabel(app.GridLayout3);
            app.YoudidnotreachmaxenergyLabel.Text = 'You did not reach max energy.';
            app.ThankyouforPlayingPleasetryagainLabel = uilabel(app.GridLayout3);
            app.ThankyouforPlayingPleasetryagainLabel.Text = 'Thank you for Playing!  Please try again!';
            app.ReturntoHomeButton_2 = uibutton(app.GridLayout3, 'push');
            app.ReturntoHomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturntoHomeButton_2Pushed, true);
            app.ReturntoHomeButton_2.Text = 'Return to Home';
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = 'Please Continue reading the exhibit and learn more about the potential of Offshore wind! Or you can play again!';
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = 'When you are done, be sure to fill out the form so we are able to improve our exhibit.';
            app.VerticalTurbineScoreEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.VerticalTurbineScoreEditFieldLabel_2.Text = 'Vertical Turbine Score';
            app.VTSEFF = uieditfield(app.GridLayout3, 'numeric');
            app.VTSEFF.ValueDisplayFormat = '%.1f%%';
            app.VTSEFF.Editable = 'off';
            app.SmallTurbineScoreEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.SmallTurbineScoreEditFieldLabel_2.Text = 'Small Turbine Score';
            app.STSEFF = uieditfield(app.GridLayout3, 'numeric');
            app.STSEFF.ValueDisplayFormat = '%.1f%%';
            app.STSEFF.Editable = 'off';
            app.LargeTurbineScoreEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.LargeTurbineScoreEditFieldLabel_2.Text = 'Large Turbine Score';
            app.LTSEFF = uieditfield(app.GridLayout3, 'numeric');
            app.LTSEFF.ValueDisplayFormat = '%.1f%%';
            app.LTSEFF.Editable = 'off';
            app.TotalEnergyEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.TotalEnergyEditFieldLabel_2.Text = 'Total Energy';
            app.TEEFF = uieditfield(app.GridLayout3, 'numeric');
            app.TEEFF.ValueDisplayFormat = '%.1f%%';
            app.TEEFF.Editable = 'off';
            app.Warning = uitab(app.TabGroup);
            app.GridLayout7 = uigridlayout(app.Warning);
            app.WARNlabel = uilabel(app.GridLayout7);
            app.WARNlabel.Text = 'Please Select A Turbine Before Starting!';
            app.NoTurbineActiveLabel = uilabel(app.GridLayout7);
            app.NoTurbineActiveLabel.Text = 'No Turbine Active :(';
            app.CD3 = uitab(app.TabGroup);
            app.GridLayout6 = uigridlayout(app.CD3);
            app.Countdown3 = uiimage(app.GridLayout6);
            app.Countdown3.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-11-05 172224.png');
            app.CD2 = uitab(app.TabGroup);
            app.GridLayout5 = uigridlayout(app.CD2);
            app.Countdown2 = uiimage(app.GridLayout5);
            app.Countdown2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-11-05 172150.png');
            app.CD1 = uitab(app.TabGroup);
            app.GridLayout4 = uigridlayout(app.CD1);
            app.Countdown1 = uiimage(app.GridLayout4);
            app.Countdown1.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-11-05 172132.png');
            app.Intro = uitab(app.TabGroup);
            app.GridLayout8 = uigridlayout(app.Intro);
            app.Image4 = uiimage(app.GridLayout8);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'P2BORG_INTROSCREEN.png');
            app.ImReadyButton = uibutton(app.GridLayout8, 'push');
            app.ImReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ImReadyButtonPushed, true);
            app.ImReadyButton.Text = 'I''m Ready';
            app.VTGScore = uieditfield(app.GridLayout, 'numeric');
            app.VTGScore.ValueDisplayFormat = '%.1f%%';
            app.VTGScore.Editable = 'off';
            app.TimerEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimerEditField.ValueDisplayFormat = '%1.2g';
            app.TimerEditField.Editable = 'off';
            app.TimerEditField.Value = 5;
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LTGScore = uieditfield(app.GridLayout, 'numeric');
            app.LTGScore.ValueDisplayFormat = '%.1f%%';
            app.LTGScore.Editable = 'off';
            app.STGScore = uieditfield(app.GridLayout, 'numeric');
            app.STGScore.ValueDisplayFormat = '%.1f%%';
            app.STGScore.Editable = 'off';
            app.LinearGauge = uigauge(app.GridLayout, 'linear');
            app.LinearGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.LinearGauge.MajorTickLabels = {'', '', '20%', '', '40%', '', '60%', '', '80%', '', '100%'};
            app.LinearGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.ScoreEditField_3Label = uilabel(app.GridLayout);
            app.ScoreEditField_3Label.Text = 'Score';
            app.LTGLabel = uilabel(app.GridLayout);
            app.LTGLabel.Text = 'Large Turbine';
            app.VTGLabel = uilabel(app.GridLayout);
            app.VTGLabel.Text = 'Vertical Turbine';
            app.TimerEditFieldLabel = uilabel(app.GridLayout);
            app.TimerEditFieldLabel.Text = 'Timer:';
            app.STGLamp = uilamp(app.GridLayout);
            app.LTGLamp = uilamp(app.GridLayout);
            app.VTGLamp = uilamp(app.GridLayout);
            app.STGSwitch = uiswitch(app.GridLayout, 'slider');
            app.STGSwitch.Items = {'Off', 'Active'};
            app.STGSwitch.ItemsData = [0 1];
            app.STGSwitch.ValueChangedFcn = createCallbackFcn(app, @STGSwitchValueChanged, true);
            app.STGSwitch.Value = 0;
            app.LTGSwitch = uiswitch(app.GridLayout, 'slider');
            app.LTGSwitch.Items = {'Off', 'Active'};
            app.LTGSwitch.ItemsData = [0 1];
            app.LTGSwitch.ValueChangedFcn = createCallbackFcn(app, @LTGSwitchValueChanged, true);
            app.LTGSwitch.Value = 1;
            app.VTGSwitch = uiswitch(app.GridLayout, 'slider');
            app.VTGSwitch.Items = {'Off', 'Active'};
            app.VTGSwitch.ItemsData = [0 1];
            app.VTGSwitch.ValueChangedFcn = createCallbackFcn(app, @VTGSwitchValueChanged, true);
            app.VTGSwitch.Value = 0;
            app.ScoreEditField_2Label = uilabel(app.GridLayout);
            app.ScoreEditField_2Label.Text = 'Score';
            app.SmallTurbineGaugeLabel = uilabel(app.GridLayout);
            app.SmallTurbineGaugeLabel.Text = 'Small Turbine';
            app.LTG = uigauge(app.GridLayout, 'circular');
            app.LTG.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.LTG.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.LTG.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.STG = uigauge(app.GridLayout, 'circular');
            app.STG.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.STG.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.STG.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.VTG = uigauge(app.GridLayout, 'circular');
            app.VTG.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.VTG.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.VTG.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.TotalEnergyLabel = uilabel(app.GridLayout);
            app.TotalEnergyLabel.Text = 'Total Energy:';
            app.TheWondersofWindLabel = uilabel(app.GridLayout);
            app.TheWondersofWindLabel.Text = 'The Wonders of Wind: ';
            app.INS2 = uitextarea(app.GridLayout);
            app.INS2.Editable = 'off';
            app.INS2.Enable = 'off';
            app.INS2.Visible = 'off';
            app.INS2.Value = {'3. Start blowing on the ‘active’ turbines to spin them as fast as you can for 5 seconds.'};
            app.ScoreEditFieldLabel = uilabel(app.GridLayout);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.INS1 = uitextarea(app.GridLayout);
            app.INS1.Editable = 'off';
            app.INS1.Value = {' 1. Select the Turbines you want to test by switching their mode to “Active”.'; '2. Then, select the “Start” button.'};
            app.Label_5 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2_TheBorg_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end